classdef RadonPotential < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        RightPanel                      matlab.ui.container.Panel
        ClimateseriesKoppenclassificationorUserseriesButtonGroup  matlab.ui.container.ButtonGroup
        AfTropicalrainforestButton      matlab.ui.control.RadioButton
        AmTropicalmonsoonButton         matlab.ui.control.RadioButton
        AwTropicalsavannahButton        matlab.ui.control.RadioButton
        BwhAriddeserthotButton          matlab.ui.control.RadioButton
        BwkAriddesertcoldButton         matlab.ui.control.RadioButton
        BshAridsteppehotButton          matlab.ui.control.RadioButton
        BskAridsteppecoldButton         matlab.ui.control.RadioButton
        CsaTemperatedrysummerhotsummerButton  matlab.ui.control.RadioButton
        CsbTemperatedrysummerwarmsummerButton  matlab.ui.control.RadioButton
        CfaTemperatenodryseasonhotsummerButton  matlab.ui.control.RadioButton
        CfbTemperatenodryseasonwarmsummerButton  matlab.ui.control.RadioButton
        DsaColddrysummerhotsummerButton  matlab.ui.control.RadioButton
        DsbColddrysummerwarmsummerButton  matlab.ui.control.RadioButton
        DwaColddrywinterhotsummerButton  matlab.ui.control.RadioButton
        DwbColddrywinterwarmsummerButton  matlab.ui.control.RadioButton
        DwcColddrywintercoldsummerButton  matlab.ui.control.RadioButton
        DfaColdnodryseasonhotsummerButton  matlab.ui.control.RadioButton
        DfbColdnodryseasonwarmsummerButton  matlab.ui.control.RadioButton
        DfcColdnodryseasoncoldsummerButton  matlab.ui.control.RadioButton
        EtPolartundraButton             matlab.ui.control.RadioButton
        UsUserseriesButton              matlab.ui.control.RadioButton
        LatitudeEditFieldLabel          matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.EditField
        TemplateButton                  matlab.ui.control.Button
        Lengthofthedataseriesdecades110EditFieldLabel  matlab.ui.control.Label
        Length_Series_Edit              matlab.ui.control.EditField
        DscColddrysummercoldsummerButton  matlab.ui.control.RadioButton
        PercentagesofSandSiltClaySSCPanel  matlab.ui.container.Panel
        SandpercentageEditField_3Label  matlab.ui.control.Label
        SandpercentageEditField         matlab.ui.control.EditField
        SiltpercentageEditField_3Label  matlab.ui.control.Label
        SiltpercentageEditField         matlab.ui.control.EditField
        ClaypercentageEditField_3Label  matlab.ui.control.Label
        ClaypercentageEditField         matlab.ui.control.EditField
        SoilcharacteristicsButtonGroup  matlab.ui.container.ButtonGroup
        TexturalclassButton             matlab.ui.control.RadioButton
        PercentagesofSandSiltClaySSCButton  matlab.ui.control.RadioButton
        Bulkdensitygcm3EditFieldLabel   matlab.ui.control.Label
        Bulkdensitygcm3EditField        matlab.ui.control.EditField
        TexturalclassButtonGroup        matlab.ui.container.ButtonGroup
        ClayButton                      matlab.ui.control.RadioButton
        ClayLoamButton                  matlab.ui.control.RadioButton
        LoamButton                      matlab.ui.control.RadioButton
        LoamySandButton                 matlab.ui.control.RadioButton
        SandButton                      matlab.ui.control.RadioButton
        SandyClayButton                 matlab.ui.control.RadioButton
        SandyClayLoamButton             matlab.ui.control.RadioButton
        SandyLoamButton                 matlab.ui.control.RadioButton
        SiltButton                      matlab.ui.control.RadioButton
        SiltyClayButton                 matlab.ui.control.RadioButton
        SiltyClayLoamButton             matlab.ui.control.RadioButton
        SiltyLoamButton                 matlab.ui.control.RadioButton
        ObtainsoilhydraulicparametersButton  matlab.ui.control.Button
        OutputvaluesEditField           matlab.ui.control.EditField
        OutputvaluesEditField_2         matlab.ui.control.EditField
        OutputvaluesEditField_3         matlab.ui.control.EditField
        OutputvaluesEditField_4         matlab.ui.control.EditField
        OutputvaluesEditField_5         matlab.ui.control.EditField
        ObtainclimateseriesButton       matlab.ui.control.Button
        OutputvaluesobtainedsuccesfullyLamp  matlab.ui.control.Lamp
        ErrorLamp                       matlab.ui.control.Lamp
        ClimateseriesobtainedsuccesfullyLamp  matlab.ui.control.Lamp
        ClimateerrorLamp                matlab.ui.control.Lamp
        AnalyzedataButton               matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        RadonPotentialLabel             matlab.ui.control.Label
        EditvaluesCheckBox              matlab.ui.control.CheckBox
        RadonconcentrationButtonGroup   matlab.ui.container.ButtonGroup
        Rn_button                       matlab.ui.control.RadioButton
        Ra_button                       matlab.ui.control.RadioButton
        RnconcentrationkBqmLabel        matlab.ui.control.Label
        Rn_concentration                matlab.ui.control.NumericEditField
        RacontentBqKgEditFieldLabel     matlab.ui.control.Label
        Ra_content                      matlab.ui.control.NumericEditField
        RnconcentrationBqm3EditFieldLabel_2  matlab.ui.control.Label
        cmcmcmcmcmnpKgLabel             matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        rsLabel                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        oLabel                          matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Alert_process_time              matlab.ui.control.Label
        RestartButton                   matlab.ui.control.Button
        UNIVERSIDADDEALICANTELabel      matlab.ui.control.Label
        Imagen_logo                     matlab.ui.control.Image
        EstimatedprocessingtimesGaugeLabel  matlab.ui.control.Label
        EstimatedprocessingtimesGauge   matlab.ui.control.Gauge
        DownloadresultsButton           matlab.ui.control.Button
        Imagen_inicio2                  matlab.ui.control.Image
        VisitorsLabel                   matlab.ui.control.Label
        VisitorsEditField               matlab.ui.control.NumericEditField
        Low_risk_label                  matlab.ui.control.Label
        Medium_risk_label               matlab.ui.control.Label
        High_risk_label                 matlab.ui.control.Label
        Low_risk_lamp                   matlab.ui.control.Lamp
        Medium_risk_lamp                matlab.ui.control.Lamp
        High_risk_lamp                  matlab.ui.control.Lamp
        UIAxes_izq_4                    matlab.ui.control.UIAxes
        UIAxes_izq_1                    matlab.ui.control.UIAxes
        UIAxes_der_1                    matlab.ui.control.UIAxes
        UIAxes_izq_2                    matlab.ui.control.UIAxes
        UIAxes_der_2                    matlab.ui.control.UIAxes
        UIAxes_izq_3                    matlab.ui.control.UIAxes
        UIAxes_der_3                    matlab.ui.control.UIAxes
        UIAxes_der_4                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        
        % Variables app.
        
        % Folders and file
        input_folder_default; 
        name_tmp;
        save_folder;
        
        % Save data_base (0-> no; 1 -> yes)
        save_database=1;
        
        % Climate series
        serieclima;
        codiclima
        
        % Minimum number of days for user climate series
        min_days=1*365;
        
        % Years of analysis by default
        default_years=10;
        default_decade=1;
        
        % Soil characteristics
        shp;
        soil_percentage;
        default_density=1.65;
        
        % Humedad
        humedadmes;
        humedaddiario;
        humedaddiario2;
        
        % Temperaturas;
        resumclimames;
                
        % Permeability
        pKg;
        pKg_diario;
        
        % Radon coefficients
        radon_coeficients;
        GRP;
        GRP_diario;
        default_Ra=10;
        default_Rn=10;
        
        % Plots
        legend_izq_1;
        legend_der_1;
        bars_der_1;
        legend_izq_2;
        legend_izq_3;
        
        % Error hydrus
        error_hydrus;
            
    end
    
    methods (Access = private)


         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
         % Hydrus execution
         %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        
        function Hydrus_execution(app)
            
            
            % Define the folders in the Data base
            input_folder=app.name_tmp; 
                                   
            save_folder2=[app.save_folder filesep num2str(app.soil_percentage(1)),'_',...
                 num2str(app.soil_percentage(2)),'_',num2str(app.soil_percentage(3))];
             
            line2=[save_folder2 filesep 'humidity'];
            line3=[save_folder2 filesep 'shp'];
            
           
            % Verify if this data exists in the current data base
            app.error_hydrus=0;
            
            error_load=0;
                        
            try
                load(line2,'humedadmes','humedaddiario','humedaddiario2');
                
            catch
                error_load=1;
            end
            
            if error_load==1
                
                if app.save_database==1   
                    
                    if isfolder(save_folder2)==0
                        mkdir(save_folder2);
                    end
                end
                
                % Prepare data file for Hydrus 
                
                
                %% PROFILE.dat file
                % Increasing nodes from 1mm to 1cm
                
                auxiliar=[0,0.1,0.1,0.2,0.2,0.3,0.3,0.4,0.4,0.5,0.5,0.6,0.6,0.7,0.7,0.8,0.8,0.9,0.9,1]';
                auxiliar=[(1:1:length(auxiliar))' auxiliar zeros(size(auxiliar,1),1)];
                
                stop=0; fila=2;
                while stop==0
                    if fila>length(auxiliar)
                        auxiliar(fila,1)=fila;
                        auxiliar(fila,2)=1;
                    end
                    auxiliar(fila,3)=auxiliar(fila-1,3)+auxiliar(fila,2);
                    if auxiliar(fila,3)>=200; stop=1;end
                    fila=fila+1;
                end
                NNODES=size(auxiliar,1);
                
                
                zPuntosObser=[20, 50, 80, 100];
                zPuntosObser_loni=length(zPuntosObser);
                
                ObservationNode=zeros(length(zPuntosObser),1);
                for i=1:length(zPuntosObser)
                    ObservationNode(i)=find(auxiliar(:,3)>=zPuntosObser(i),1);
                end
                
                
                cFileName = [input_folder filesep 'Template' filesep 'PROFILE_template.DAT'];
                f1= fopen(cFileName, 'r');
                
                cFileName = [input_folder filesep 'Template' filesep 'PROFILE.dat'];
                f2= fopen(cFileName, 'w');
                
                for k=1:4; line=fgetl(f1); fprintf(f2,line); fprintf(f2, '\n');end % Copy the first 4 lines
                line=fgetl(f1);        % 5th line
                fprintf(f2, '%5.0f',NNODES);
                fprintf(f2,line(6:end)); fprintf(f2, '\n');
                
                profileend=zeros(NNODES, 9);    % Preallocation
                profileend(:,1)=auxiliar(:,1);  % Nodes
                profileend(:,2)=-auxiliar(:,3); % Depths
                profileend(:,3)=-100;           % Initial sph
                profileend(:,4)=1;              % Material
                profileend(:,5)=1;              % Layer
                profileend(:,7:9)=1;            % Scaling factor
                
                fprintf(f2,'%5.0d%15.2f%15.3f%5.0d%5.0d%15.3f%15.3f%15.3f%15.3f\n',profileend');
                fprintf(f2, '%5.0f',length(zPuntosObser));   fprintf(f2,'\n');       % Number of observation points
                fprintf(f2, '%5.0f%5.0f%5.0f%5.0f%5.0f%5.0f%5.0f%5.0f%5.0f%5.0f%5.0f\n', ObservationNode);   % Observation nodes
                fclose all;
                clear f1 f2;
                
                %% HYDRUS1D.DAT file
                
                cFileName = [input_folder filesep 'Template' filesep 'HYDRUS1D_template.DAT'];
                f1= fopen(cFileName, 'r');
                
                cFileName = [input_folder filesep 'Template' filesep 'HYDRUS1D.DAT'];
                f2= fopen(cFileName, 'w');
                
                for k=1:21
                   line=fgetl(f1); 
                   fprintf(f2,line); 
                   fprintf(f2, '\n');
                end 
                
                % Copy the first 4 lines
                line=fgetl(f1);   
                temp=strfind(line,'=');     % 22th line
                fprintf(f2,line(1:temp)); 
                fprintf(f2, '%3.0f',NNODES); 
                fprintf(f2, '\n');
                line=fgetl(f1); 
                temp=strfind(line,'=');
                fprintf(f2,line(1:temp)); 
                fprintf(f2, '%4.0f',auxiliar(NNODES,3)); 
                fprintf(f2, '\n');
                
                while ~feof(f1)
                    line=fgetl(f1); 
                    fprintf(f2,line); 
                    fprintf(f2, '\n'); % Copy to the end
                end
                fclose all;
                clear f1 f2;
                
                
                %% ATMOSPHER.in file
                
                % Copy first year at the beginning of the series (five
                % times)
                
                serie_1a=app.serieclima(1:365,:);
                serie_ext=[serie_1a;serie_1a;serie_1a;serie_1a;serie_1a;app.serieclima];
             
               
                cFileName = [input_folder filesep 'Template' filesep 'ATMOSPH_template.IN'];
                f1= fopen(cFileName, 'r');
                
                cFileName = [input_folder filesep 'Template' filesep 'ATMOSPH.IN'];
                f2= fopen(cFileName, 'w');
                for k=1:3
                    line=fgetl(f1); 
                    fprintf(f2,line); 
                    fprintf(f2, '\n'); 
                end 
                
                fgetl(f1); 
                fprintf(f2,'%7.0f',size(serie_ext,1));   
                fprintf(f2, '\n');
                
                for k=1:5 
                    line=fgetl(f1); 
                    fprintf(f2,line); 
                    fprintf(f2, '\n'); 
                end 
                
                atmospher=(1:size(serie_ext,1))';
                atmospher=[atmospher serie_ext(:,[4,15])/10]; % Day, precipitation and evaporation. Convert from mm to cms !!!!
                atmospher=[atmospher zeros(size(atmospher,1),5)];
                atmospher(:,5)=100000;
                fprintf(f2,'%11.0f%12.3f%12.3f%12.0f%12.0f%12.0f%12.0f%12.0f\n',atmospher');
                fprintf(f2,'end*** END OF INPUT FILE ''ATMOSPH.IN'' **********************************');
                fclose all;
                clear f1 f2; 
                
                %% SELECTOR.IN file
                                
                cFileName = [input_folder filesep 'Template' filesep 'SELECTOR_template.IN'];
                f1= fopen(cFileName, 'r');
                
                cFileName = [input_folder filesep 'Template' filesep 'SELECTOR.IN'];
                f2= fopen(cFileName, 'w');
                
                for k =1:26
                    line=fgetl(f1);
                    fprintf(f2,line); 
                    fprintf(f2, '\n'); 
                end
                
                fprintf(f2,'%7.3f%8.4f%8.4f%8.4f%11.2f%8.1f\n',[app.shp 0.5]); % Paste shp
                fgetl(f1); 
                
                for k=1:4
                    line=fgetl(f1);
                    fprintf(f2,line); 
                    fprintf(f2, '\n'); 
                end 
                
                fgetl(f1);
                fprintf(f2, '%11.0f%15.0f', [0 size(atmospher,1)]); fprintf(f2, '\n');
                
                for k=1:3
                    line=fgetl(f1);
                    fprintf(f2,line); 
                    fprintf(f2, '\n');
                end 
                
                fgetl(f1); 
                fprintf(f2, '%11.0f', size(atmospher,1));  
                fprintf(f2, '\n');
                line=fgetl(f1); 
                fprintf(f2,line); 
                fprintf(f2, '\n');
                fclose all;
                clear f1 f2; 
            
            
                FileName = [input_folder filesep 'Template' filesep 'Obs_Node.out'];
                delete(FileName);
 
                
                %% Running HYDRUS
                tic;
               
                cd(input_folder);
                system('H1D_CALC.EXE &');
                  
                % Estimate calculation time and supervise the process
                % according to the time and the size of the output file
                
                % Identification of non-convergence runs 
                
                sigue=0;
                time_estima_max=35*app.default_decade;
                time_estima=8*30*app.default_decade;
                size_estima=10000000*app.default_decade;
                paso_estima=time_estima/30;
                
                app.EstimatedprocessingtimesGauge.Limits=[0 time_estima];
                app.EstimatedprocessingtimesGauge.Value=0;
                
                paso_lee=30;
                nn=1;
                lim_nn=8;
                
                while sigue==0
                    
                     tto=toc;
                   
                     app.EstimatedprocessingtimesGauge.Value=round(tto);
                     pause(1);
                    
                     if tto>=nn*paso_lee
                         
                          text = fileread(FileName);
                          
                          if contains(text,'end')==1
                              sigue=1;
                             
                          else
                              
                              if nn==lim_nn
                                  sigue=2;
                                  app.error_hydrus=1;
                              else
                                  nn=nn+1;
                              end
                              
                          end
                         
                     end
                     

                end
                
                app.EstimatedprocessingtimesGauge.Value=time_estima;
              
                toc;
               
                
                %% Reading results from OBS_NODE.OUT file
                
                if app.error_hydrus==0
              
                    a=importdata(FileName, ' ',11); 
                    a=a.data;
                                        
                    ahumedad=a(:,[1,3,6,9,12]);
                    ahumedad(ahumedad(:,1)<365*5,:)=[]; % Remove the first 5 years
                    ahumedad(:,1)=ahumedad(:,1)-365*5;
                    
                    % Daily himidity
                    humedaddiario=zeros(365*(app.default_years-1),5);
                    
                    for idia=1:365*(app.default_years)   
                        tempfilas=find(ahumedad(:,1)<=idia & ahumedad(:,1)>idia-1);
                        humedaddiario(idia,1)=idia;
                        
                        humedaddiario(idia,2:5)=mean(ahumedad(tempfilas,2:end));
                    end
                    
                    % Median monthly humidity
                    diasmes=[31 28 31 30 31 30 31 31 30 31 30 31];
                    humedadmes=zeros(length(zPuntosObser),12);
                    % Identify incomplete periods
                    tempnan=isnan(humedaddiario(:,5)); 
                    [fila,~]=find(tempnan==1);
              
                    humedaddiario2=humedaddiario;
                   
                    humedaddiario2(fila,:)=[];
                    
                    humedaddiario=humedaddiario2;
                    
                    
                    if size(humedaddiario2,1)==0
                        app.error_hydrus=1;
                    end
                    
                    
                    if app.error_hydrus==0
                        
                        for imes=1:12
                                                        
                            temp=find(app.serieclima(:,2)==imes);
                            humedadmes(:,imes)=mean(humedaddiario2(temp,2:end))';
                            clear temp
                        end
                        

                        if app.save_database==1
                            save(line2,'humedadmes','humedaddiario','humedaddiario2');
                            
                            shp=app.shp;
                            percentages=[app.soil_percentage(1) app.soil_percentage(2) app.soil_percentage(3)];
                            save(line3,'shp','percentages');
                        end
                  
                    end
                end    
            end
            

            if app.error_hydrus==0

                app.humedadmes=humedadmes; 
                app.humedaddiario=humedaddiario;
                app.humedaddiario2=humedaddiario2;
            end
           
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Post processing of the data
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function postproccessing(app)


            % Saturation status            
            
            swdiario=(app.humedaddiario2(:,2:end)-app.shp(1))./(app.shp(2)-app.shp(1));
            swdiario=[app.humedaddiario2(:,1) swdiario];
            swmes=(app.humedadmes-app.shp(1))./(app.shp(2)-app.shp(1));
            
            % Permeability
            k=(app.shp(5)/100/24/3600)*0.00131/9.81/1000; % Porous medium
  
            % Estimation of Kl and Kg
            Kwdiario=zeros(size(swdiario));
            kwdiario=zeros(size(swdiario));
            kgdiario=zeros(size(swdiario));
         
            m=1-app.shp(4)^-1;

            Kwdiario(:,2:5)=app.shp(5).*(swdiario(:,2:5).^0.5).*(1-(1-swdiario(:,2:5).^(1/m)).^m).^2;  % cm/day
            kwdiario(:,2:5)=k.*(swdiario(:,2:5).^0.5).*(1-(1-swdiario(:,2:5).^(1/m)).^m).^2;           % m2
            kgdiario(:,2:5)=k.*(1-swdiario(:,2:5).^0.5).*(1-(1-(1-swdiario(:,2:5)).^(1/m)).^m).^2;     % m2
       
            Kwmes=zeros(size(swmes));  
            kwmes=zeros(size(swmes));
            kgmes=zeros(size(swmes));
            for ifi=1:4 
                for ic=1:12
                    Kwmes(ifi,ic)=k*(swmes(ifi,ic)^0.5)*(1-(1-swmes(ifi,ic)^(1/m))^m)^2;
                    kwmes(ifi,ic)=k*(swmes(ifi,ic)^0.5)*(1-(1-swmes(ifi,ic)^(1/m))^m)^2;
                    kgmes(ifi,ic)=k*(1-swmes(ifi,ic)^0.5)*(1-(1-(1-swmes(ifi,ic))^(1/m))^m)^2;
                end
            end    
            
            %% Risk clasification according to permeability
            % Reference for equations: Neznal (2002) pag 17-19
            % RI low: 1, medium: 2, high: 3
            ca=[20 50 80]; % Three levels of radon production, in [kBq/m3]
            ksup=0.1.*ca+9.9;
            kinf=ca./35+9.971;
            ksup=10.^-ksup;
            kinf=10.^-kinf;
            kgmesclasi_bajac=zeros(size(kgmes));
            for ifi=1:4 
                for ic=1:12
                    if kgmes (ifi,ic)<ksup(1)
                        kgmesclasi_bajac(ifi,ic)=1;
                    elseif kgmes(ifi,ic)>kinf(1)
                        kgmesclasi_bajac(ifi,ic)=3;
                    else
                        kgmesclasi_bajac(ifi,ic)=2;
                    end
                end
            end  
            kgmesclasi_mediac=zeros(size(kgmes));
            for ifi=1:4  
                for ic=1:12
                    if kgmes (ifi,ic)<ksup(2)
                        kgmesclasi_mediac(ifi,ic)=1;
                    elseif kgmes(ifi,ic)>kinf(2)
                        kgmesclasi_mediac(ifi,ic)=3;
                    else
                        kgmesclasi_mediac(ifi,ic)=2;
                    end
                end
            end 
            kgmesclasi_altac=zeros(size(kgmes));
            for ifi=1:4 
                for ic=1:12
                    if kgmes (ifi,ic)<ksup(3)
                        kgmesclasi_altac(ifi,ic)=1;
                    elseif kgmes(ifi,ic)>kinf(3)
                        kgmesclasi_altac(ifi,ic)=3;
                    else
                        kgmesclasi_altac(ifi,ic)=2;
                    end
                end
            end 
           
            app.pKg=kgmes;
            app.pKg_diario=kgdiario;
            
           
            diasmes=[31 28 31 30 31 30 31 31 30 31 30 31];
            resumclimames=zeros(5,12);
            
            % col 1: precipt: col 2: tmax; col3: tmin; col 4: ET0, Col5: E
            
            for imes=1:12
                tempfilas=find(app.serieclima(:,2)==imes);
                resumclimames(1,imes)=sum(app.serieclima(tempfilas,4))/100;  % Sum of the monthly means
                resumclimames(2,imes)=mean(app.serieclima(tempfilas,8));     % Diary mena
                resumclimames(3,imes)=mean(app.serieclima(tempfilas,9));     % Diary mean
                resumclimames(4,imes)=sum(app.serieclima(tempfilas,14))/100; % Sum of the monthly mean
                resumclimames(5,imes)=sum(app.serieclima(tempfilas,15))/100; % Sum of the monthly mean
                clear tempfilas
            end
            resumclimaano=zeros(5,1);
            resumclimaano(1,1)=sum(app.serieclima(:,4))/100;
            resumclimaano(2,1)=mean(app.serieclima(:,8));
            resumclimaano(3,1)=mean(app.serieclima(:,9));
            resumclimaano(4,1)=sum(app.serieclima(:,14))/100;
            resumclimaano(5,1)=sum(app.serieclima(:,15))/100;
            
            app.resumclimames=resumclimames;
  
        end

        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Graphical output results
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function plot_results(app)
          
            % Axes positions
            
            izq_1=[100,1100,504,301];
            izq_2=[100,750,504,301];
            izq_3=[100,400,504,301];
            izq_4=[100,50, 504,301];
            
            der_1=[652,1100,504,301];
            der_2=[652,750,504,301];
            der_3=[652,400,504,301];
            der_4=[652,50,504,301];

            fig=app.UIFigure;

            % Figure 1 left
            % Temperature summary
            ax_izq_1=app.UIAxes_izq_1;
            ax_izq_1.Visible='on';
            hold(ax_izq_1,"off");
            ax_izq_1.Title.String='Climate outputs';
            ax_izq_1.Position=izq_1;
            
           
            h1=plot(ax_izq_1,1:12,app.resumclimames(2,:)); %tmax
            hold(ax_izq_1,"on");
            h2=plot(ax_izq_1,1:12,app.resumclimames(3,:)); %tmin
            
            h1.Color='b';
            h1.LineWidth=3;
            h2.Color='r';
            h2.LineWidth=3;
            
            ax_izq_1.YColor='k';
            ax_izq_1.YLabel.String='Temperature (°C)';
            ax_izq_1.XTick=1:12;
            ax_izq_1.XTickLabel={'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
            ax_izq_1.XLabel.String='';
            ax_izq_1.XLim=[0.5 12.5];
            
             grid(ax_izq_1,"on");
            
            hl=legend([h1,h2],'Tmax','Tmin','location','northeast');
            hl.FontSize=9;
            
            app.legend_izq_1=h1;
           
            disableDefaultInteractivity(ax_izq_1);
            
            % Figure 1 right
            % Summary of precipitation and ET0
            
            ax_der_1=app.UIAxes_der_1;
            ax_der_1.Visible='on';
            hold(ax_der_1,"off");
            ax_der_1.Title.String='Climate outputs';
            ax_der_1.Position=der_1;
            
            yyaxis(ax_der_1,'left');
            
            h1=bar(ax_der_1,1:12,app.resumclimames(1,:));
            app.bars_der_1=h1;
            
            hold(ax_der_1,"on");
            h1.FaceColor=[0.3 0.3 0.3];
            ax_der_1.YColor='k';
            ax_der_1.YLabel.String='Mean total precipitation (mm)';
            ax_der_1.XTickLabel={'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
            ax_der_1.XLabel.String='';
           
            ax_der_1.XLim=[0.5 12.5];
            
            yyaxis(ax_der_1,"right");
            h4=plot(ax_der_1,1:12,app.resumclimames(4,:));
            h4.Color='b';h4.LineWidth=3;
            ax_der_1.YColor='k';
            ax_der_1.YLabel.String='Mean total ET0 (mm)';
            
            hl=legend([h1,h4],'Precipitations', 'ET0','location','northeast');
            hl.FontSize=9;
            app.legend_der_1=h1;
            
            disableDefaultInteractivity(ax_der_1);
            
            % Figure 2 left
            % Humedidity
            
            ax_izq_2=app.UIAxes_izq_2;
            ax_izq_2.Visible='on';
            hold(ax_izq_2,"off");
            ax_izq_2.Title.String='Volumetric water content (-)';
            ax_izq_2.Position=izq_2;
                                        
            h1=plot(ax_izq_2,1:12,app.humedadmes(1,:));  % 20
            hold(ax_izq_2,"on");
            h2=plot(ax_izq_2,1:12,app.humedadmes(2,:));  % 50
            h3=plot(ax_izq_2,1:12,app.humedadmes(3,:));  % 80
            h4=plot(ax_izq_2,1:12,app.humedadmes(4,:));  % 100
   
            h1.Color='k';h1.LineStyle='-';h1.LineWidth=3;
            h2.Color='b';h2.LineStyle='-';h2.LineWidth=3;
            h3.Color='r';h3.LineStyle='-';h3.LineWidth=3;
            h4.Color='c';h4.LineStyle='-';h4.LineWidth=3;
  
            ax_izq_2.XTick=1:12;
            ax_izq_2.XTickLabel={'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
            ax_izq_2.XLim=[0.5 12.5];
            
            ax_izq_2.YLabel.String='Volumetric water content (-)';
            ax_izq_2.XLabel.String='';
            
             grid(ax_izq_2,"on");
            
            hl=legend([h1,h2,h3,h4], '20 cm',...
                '50 cm','80 cm','100 cm','location','northwest');
          
            hl.FontSize=9;
            app.legend_izq_2=h1;    

            disableDefaultInteractivity(ax_izq_2);
            
            % Figure 2 right
            % Histogram of humidity
            
            var=app.humedaddiario(:,4);
            
            ax_der_2=app.UIAxes_der_2;
            ax_der_2.Visible='on'; 
            ax_der_2.Title.String='Hystogram of Volumetric water content at 1m';
            ax_der_2.Position=der_2;
                                        
            h1=histogram(ax_der_2,var,128);  % 100
            ax_der_2.XLabel.String='Volumetric water content (-)';
            ax_der_2.YLabel.String='';
            hl.FontSize=9;
            
            xxy=axis(ax_der_2);
            x1=xxy(2);
            y1=0.95*xxy(4);
              
            str = {['Median: ',num2str(median(var))],['STD: ',num2str(std(var))],['Kurtosis: ',num2str(kurtosis(var))],['Skewness: ',num2str(skewness(var))]};
            text(ax_der_2,x1,y1,str,'VerticalAlignment','top','HorizontalAlignment','right');
        
            disableDefaultInteractivity(ax_der_2);
            
            % Figure 3 left
            % Permeability 
            
            ax_izq_3=app.UIAxes_izq_3;
            ax_izq_3.Visible='on';
            hold(ax_izq_3,"off");
             
            ax_izq_3.Title.String='Permeability';
            ax_izq_3.Position=izq_3;
            
            h1=plot(ax_izq_3,1:12,-log10(app.pKg(1,:)));  % 20
            hold(ax_izq_3,"on");
            h2=plot(ax_izq_3,1:12,-log10(app.pKg(2,:)));  % 50
            h3=plot(ax_izq_3,1:12,-log10(app.pKg(3,:)));  % 80
            h4=plot(ax_izq_3,1:12,-log10(app.pKg(4,:)));  % 100
              
            h1.Color='k';h1.LineStyle='-';h1.LineWidth=3;
            h2.Color='b';h2.LineStyle='-';h2.LineWidth=3;
            h3.Color='r';h3.LineStyle='-';h3.LineWidth=3;
            h4.Color='c';h4.LineStyle='-';h4.LineWidth=3;
   
            ax_izq_3.XTick=1:12;
            ax_izq_3.XTickLabel={'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
            ax_izq_3.XLim=[0.5 12.5];
            
            ax_izq_3.YLabel.String='pk_{g}=-log k_{g} (k_{g} in m^{2})';
            ax_izq_3.XLabel.String='';
            
            grid(ax_izq_3,"on");
            
            hl=legend([h1,h2,h3,h4], '20 cm',...
                '50 cm','80 cm','100 cm','location','northwest');
              
            hl.FontSize=9;
            app.legend_izq_3=h1;
        
            disableDefaultInteractivity(ax_izq_3);
              
            % Figure 3 right
            % Histogram of the permeability
            
            var=-log10(app.pKg_diario(:,4));
            
            ax_der_3=app.UIAxes_der_3;
            ax_der_3.Visible='on'; 
            ax_der_3.Title.String='Hystogram of Permeability at 1m';
            ax_der_3.Position=der_3;
                                        
            h1=histogram(ax_der_3,var,128);  % 100
            ax_der_3.XLabel.String='pk_{g}=-log k_{g} (k_{g} in m^{2})';
            ax_der_3.YLabel.String='';
            
            hl.FontSize=9;
            
            xxy=axis(ax_der_3);
            x1=xxy(2);
            y1=0.95*xxy(4);
              
            str = {['Median: ',num2str(median(var))],['STD: ',num2str(std(var))],['Kurtosis: ',num2str(kurtosis(var))],['Skewness: ',num2str(skewness(var))]};
            text(ax_der_3,x1,y1,str,'VerticalAlignment','top','HorizontalAlignment','right');
        
            disableDefaultInteractivity(ax_der_3);
            
            % Figure 4 left
            % Radon
            
            ax_izq_4=app.UIAxes_izq_4;
            ax_izq_4.Visible='on';
            
            ax_izq_4.Title.String='Geogenic radon potential (GRP)';
            ax_izq_4.Position=izq_4;
                      
            grp_low=find(app.GRP<10);
            grp_medium=find(app.GRP>=10 & app.GRP<35);
            grp_high=find(app.GRP>=35);
            
            hold(ax_izq_4,'off');
            h1=plot(ax_izq_4,1:12,app.GRP); 
            h1.LineWidth=3;
            axis(ax_izq_4,'tight');
            gg=axis(ax_izq_4);
            
            hold(ax_izq_4,'on');
            
            if isempty(grp_low)==0
                h1a=plot(ax_izq_4,grp_low,app.GRP(grp_low),'go'); 
                h1a.LineWidth=3;
                
            end
            
            if isempty(grp_medium)==0
                h1b=plot(ax_izq_4,grp_medium,app.GRP(grp_medium),'yo'); 
                h1b.LineWidth=3;
            end
            
            if isempty(grp_high)==0
                h1c=plot(ax_izq_4,grp_high,app.GRP(grp_high),'ro'); 
                h1c.LineWidth=3;
            end
            
            hold(ax_izq_4,'off');
        
            ax_izq_4.XTick=1:12;
            ax_izq_4.XTickLabel={'Jan','Feb','Mar','Apr','May','Jun','Jul','Aug','Sep','Oct','Nov','Dec'};
            ax_izq_4.XLim=[0.5 12.5];
            
            ax_izq_4.YLabel.String='GRP (-)';
            ax_izq_4.XLabel.String='';
            
            gg(4)=gg(4)+((gg(4)-gg(3))/2);
            axis(ax_izq_4,gg);
            
            grid(ax_izq_4,"on");
             
            app.High_risk_lamp.Visible='on';
            app.Medium_risk_lamp.Visible='on';
            app.Low_risk_lamp.Visible='on';
            app.High_risk_label.Visible='on';
            app.Medium_risk_label.Visible='on';
            app.Low_risk_label.Visible='on';
             
             disableDefaultInteractivity(ax_izq_4);

            % Figure 4 right
            % Histogram of the radon
            
            var=app.GRP_diario;
            
            ax_der_4=app.UIAxes_der_4; 
            ax_der_4.Visible='on';      
            ax_der_4.Title.String='Hystogram of GRP';
            ax_der_4.Position=der_4;
                                        
            h1=histogram(ax_der_4,var,128);  % 100
            axis(ax_der_4,'tight');
            hold(ax_der_4,'on');
            
            %%%%%%%%%%%%%%%
            
            [nbin,inde] = histcounts(var,128);
            inde=inde(1:end-1);
            ind1=find(inde<10);
            ind2=find(inde>=10 & inde<35);
            ind3=find(inde>=35);
            b11 = bar(ax_der_4,inde(ind1),nbin(ind1), 'facecolor', 'flat');
            
            b12 = bar(ax_der_4,inde(ind2),nbin(ind2), 'facecolor', 'flat');
            b13 = bar(ax_der_4,inde(ind3),nbin(ind3), 'facecolor', 'flat');
            hold(ax_der_4,'off');
            b11.CData=[0 1 0];
            b12.CData=[1 1 0];
            b13.CData=[1 0 0];
         
            %%%%%%%%%%%%%%%
            
            ax_der_4.XLabel.String='GRP (-)';
            ax_der_4.YLabel.String='';
            
            xxy=axis(ax_der_4);
            x1=xxy(2);
            y1=0.95*xxy(4);
            
                          
            str = {['Median: ',num2str(median(var))],['STD: ',num2str(std(var))],['Kurtosis: ',num2str(kurtosis(var))],['Skewness: ',num2str(skewness(var))]};
            text(ax_der_4,x1,y1,str,'VerticalAlignment','top','HorizontalAlignment','right');
            
            disableDefaultInteractivity(ax_der_4);
        
        end
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Save output results
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function save_results(app)
            
            loni=min(length(app.serieclima(:,4)),length(app.humedaddiario(:,4)));
                       
            
            % Create daily matrix
            
            serie_diaria(:,1)=1:loni;                           % Number of days
            serie_diaria(:,2)=app.serieclima(1:loni,8);         % T max
            serie_diaria(:,3)=app.serieclima(1:loni,9);         % T min
            serie_diaria(:,4)=app.serieclima(1:loni,4);         % Precipitation
            serie_diaria(:,5)=app.serieclima(1:loni,14);        % ET0
            serie_diaria(:,6)=app.humedaddiario(1:loni,4);      % Volumetric_Water_Content (1m)
            serie_diaria(:,7)=-log10(app.pKg_diario(1:loni,4)); % pKg (1m)
            serie_diaria(:,8)=app.GRP_diario;                   % GRP
            
                  
            % Create monthly matrix
            
            serie_mes(:,1)=1:12;                            % Number of months
            serie_mes(:,2)=app.resumclimames(2,:)';         % T max
            serie_mes(:,3)=app.resumclimames(3,:)';         % T min
            serie_mes(:,4)=app.resumclimames(1,:)';         % Precipitation
            serie_mes(:,5)=app.resumclimames(4,:)';         % ET0
            serie_mes(:,6)=app.humedadmes(4,:)';            % Volumetric_Water_Content (1m)
            serie_mes(:,7)=-log10(app.pKg(4,:))';           % pKg (1m)
            serie_mes(:,8)=app.GRP;                         % GRP
            
                       
            % Header
            head_1d='Day Tmax Tmin Precipitation Evapotranspiration Volumetric_Water_Content pKg GRP';
            head_1m='Month Tmax Tmin Precipitation Evapotranspiration Volumetric_Water_Content pKg GRP';
            head_2='# C C mm mm (-) (-) (-)';
            
            % Request name
            
            name_d=[app.name_tmp filesep 'results_daily.txt'];
            name_m=[app.name_tmp filesep 'results_monthly.txt'];
            name_zip=[app.name_tmp filesep 'results.zip'];
          
            % Save results
            % Daily series
            fid=fopen(name_d,'wt');
            fprintf(fid,head_1d);
            fprintf(fid,'\n');
            fprintf(fid,head_2);
            fprintf(fid,'\n');
            fclose(fid);

            dlmwrite(name_d,serie_diaria,'-append','delimiter',' ');

            % Montly series
            fid=fopen(name_m,'wt');
            fprintf(fid,head_1m);
            fprintf(fid,'\n');
            fprintf(fid,head_2);
            fprintf(fid,'\n');
            fclose(fid);

            dlmwrite(name_m,serie_mes,'-append','delimiter',' ');

            zip(name_zip,{name_d,name_m});
            
            % Download file
            [file,path] = uiputfile(name_zip);
            
            if file>0
                resultFilePath = fullfile(path,file);
                copyfile(name_zip,resultFilePath,'f');
            end
            
        end
        
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Check the parameters for Radon diffusion
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function error=test_radon_options(app)
       
            % Marcador de error 
            error=0;
            
            % Radon / Radio selection
            Rn_act=double(app.Rn_button.Value);
            
            if Rn_act==1
            
                % Radon concentration
                Gas_con=app.Rn_concentration.Value;
                
                if Gas_con<=0 || isempty(Gas_con)==1
                    app.ClimateerrorLamp.Visible='on';
                    app.ClimateseriesobtainedsuccesfullyLamp.Visible='off';
                    app.Label_2.Visible='on';
                    app.Label_2.Text='Error: Radon concentration is required';
                    
                    error=1;
                end
            
            else
                
                % Bulk density
                Density = str2double(app.Bulkdensitygcm3EditField.Value);
            
                if Density<=0 || isempty(Density)==1
                    
                    app.ClimateerrorLamp.Visible='on';
                    app.ClimateseriesobtainedsuccesfullyLamp.Visible='off';
                    app.Label_2.Visible='on';
                    app.Label_2.Text='Error: Bulk density of the soil is required';
                    
                    error=1;
                end
                
                % Radio content
                Rad_con=app.Ra_content.Value;
                
                if Rad_con<=0 || isempty(Rad_con)==1
                    app.ClimateerrorLamp.Visible='on';
                    app.ClimateseriesobtainedsuccesfullyLamp.Visible='off';
                    app.Label_2.Visible='on';
                    app.Label_2.Text='Error: Radio content is required';
                    
                    error=1;
                end
                
            end
            
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Analysis of radon concentration
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function radon_concentration(app)
            
            input_folder=app.input_folder_default;
            
            % Radon / Radio selection
            Rn_act=double(app.Rn_button.Value);
            
            if Rn_act==0
            
                % Radio content
                Ra_con=app.Ra_content.Value;
 
                % Parametrization
                delimiterIn=',';
                headerlinesIn=1;
                archivo2=importdata([input_folder filesep 'Radon_coefficients' filesep 'Radon_coef.txt'],delimiterIn,headerlinesIn);
                val=archivo2.data;
                app.radon_coeficients=val*(app.soil_percentage/100)';
                
                
                % Emanation coefficient
                
                % Humidity at 1m -> app.humedadmes(4,:))
                termi1=1+app.radon_coeficients(2)*(1-exp(-app.radon_coeficients(3)*app.humedadmes(4,:)));
                termi1_diario=1+app.radon_coeficients(2)*(1-exp(-app.radon_coeficients(3)*app.humedaddiario2(:,4)));
                               
                termi2=1+app.radon_coeficients(4)*app.resumclimames(2,:);
                termi2_diario=1+app.radon_coeficients(4)*app.serieclima(1:length(app.humedaddiario2),8);
                
                epsilon=app.radon_coeficients(1).*termi1.*termi2;
                epsilon_diario=app.radon_coeficients(1).*termi1_diario.*termi2_diario;
               
                % Bulk density
                % Multiply by 1000 (kg/m^3)
                Density = str2double(app.Bulkdensitygcm3EditField.Value);
                
                % Rn concentration
                CRn=Ra_con.*epsilon.*Density;
                CRn_diario=Ra_con.*epsilon_diario.*Density;
      
            else
                
                % Radon  concentration
                CRn=app.Rn_concentration.Value;
                CRn_diario=CRn.*ones(length(app.humedaddiario2),1);
                                
            end  
            
            % Geogenic radon potential
            % Permeability at 1m -> app.pKg(4,:)
            % CRn has to be in kBq/m3
            GRP=CRn./(-log10(app.pKg(4,:))-10);
            
            GRP_diario=CRn_diario./(-log10(app.pKg_diario(:,4))-10);
            
            app.GRP=GRP;
            app.GRP_diario=GRP_diario;
            
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % The input_folder is saved in the file 'parameters.txt'
            ff=fopen('C:\RadonRisk\Toolbox\parameters.txt','rt');
            app.input_folder_default=fgetl(ff);
            fclose(ff);
            
            % Folder where the results are temporally saved
            app.name_tmp=[app.input_folder_default filesep 'results_' datestr(now,'yymmddHHMMSS')];
            
            % Folders where Hydrus is executed and its results are saved
            name_Template=[app.name_tmp filesep 'Template'];
            name_level=[app.name_tmp filesep 'LEVEL_01.DIR'];
            
            mkdir(app.name_tmp);
            mkdir(name_Template);
            
            % Copy Hydrus to the temporal folder
            
            copyfile('C:\RadonRisk\Toolbox\H1D_CALC.EXE',app.name_tmp);
            copyfile('C:\RadonRisk\Toolbox\Template',name_Template);
            
            dlmwrite(name_level,name_Template,'delimiter','');
            
            
            % Remove temporally folders after 5 hours
            dif_hour_min=5;
            date1=datetime;
            name_rem=[app.input_folder_default filesep 'results_*'];
            
            ss=dir(name_rem);
    
            if isempty(ss)==0
            
                for ind1=1:length(ss)
                    
                    ss_date=ss(ind1).datenum;
                    ss_date2 = datetime(ss_date,'Format','dd-MMM-yyyy HH:mm:ss','convertFrom','datenum');
                    
                    dif_hour=hours(date1-ss_date2);
                    
                    if dif_hour>=dif_hour_min
                        
                        ss_folder=ss(ind1).folder;
                        ss_name=ss(ind1).name;
                        
                        file_to_remove=[ss_folder filesep ss_name];
                        
                        rmdir(file_to_remove,'s');
                        
                    end
                   
                end
            end
            
           % Count the number of entries in the web application
           name_users=[app.input_folder_default filesep 'users'];
           load(name_users)
           number_users=number_users+1;
           app.VisitorsEditField.Value=number_users;
           save(name_users,'number_users');
          
            
        end

        % Callback function
        function InputselectionDropDownValueChanged(app, event)
           
            % Empty function
           
        end

        % Button pushed function: ObtainclimateseriesButton
        function Climate_Series_Button(app, event)
            
            input_folder=app.input_folder_default;
            
            selectedButton = app.ClimateseriesKoppenclassificationorUserseriesButtonGroup.SelectedObject;
            
            % Lis of possible climas     
            list={'[Af] Tropical, rainforest', '[Am] Tropical, monsoon','[Aw] Tropical, savannah',...
            '[Bwh] Arid,desert,hot','[Bwk] Arid,desert,cold','[Bsh] Arid, steppe, hot',...
            '[Bsk] Arid, steppe, cold','[Csa] Temperate, dry summer, hot summer',...
            '[Csb] Temperate, dry summer, warm summer', '[Cfa] Temperate, no dry season, hot summer',...
            '[Cfb] Temperate, no dry season, warm summer','[Dsa] Cold, dry summer, hot summer',...
            '[Dsb] Cold, dry summer, warm summer','[Dsc] Cold, dry summer, cold summer','[Dwa] Cold, dry winter, hot summer',...
            '[Dwb] Cold, dry winter, warm summer','[Dwc] Cold, dry winter, cold summer',...
            '[Dfa] Cold, no dry season, hot summer','[Dfb] Cold, no dry season, warm summer',...
            '[Dfc] Cold, no dry season, cold summer','[Et] Polar, tundra','[Us] User series'};
                    
            clima=find(strcmp(list,selectedButton.Text));
            
            temp=list{clima};
            app.codiclima=temp(strfind(temp,'['):strfind(temp,']'));
            
  
            % Read the latitude parameter
            lat=str2double(app.LatitudeEditField.Value);
            
            
            if isnan(lat)
                lat=0;
            end
            
            if lat>70 || lat<-70
                
                error_ssb=1;
                error_txt='Error: Latitude has to be into the range [-70,70]';
                
            else
                
                error_ssb=0;
                error_txt='Climate series obtained succesfully';
            
                if lat>0 
                    hemis=1;        %1 means North
                else
                    hemis=0;        %0 means South
                end
                
                % Import climate series
                base_datos=0;
                
                % Path where the files are saved
                line1=[num2str(app.default_decade) filesep app.codiclima(2:end-1),filesep,num2str(round(10*lat))];
                app.save_folder=[input_folder filesep 'Data_base' filesep line1];    
                
                % User selection
                    
                if clima ==22 % '[Us] User series'
                   
                  app.save_database=0;  
                                     
                  [file, path] = uigetfile({'*.txt'}, 'Select a climate series');
                  
                  if isequal(file,0)
                    error_ssb=1;
                    error_txt='Error: Climate series has not been loaded';              
                  
                  else
                    
                    error_ssb=0;
                    error_txt='Climate series obtained succesfully';  
                      
                    % Read input file
                    archivoimportar = fullfile(path,file);
                    
                    fid=fopen(archivoimportar,'rt');
                    
                    if fid~=-1
                    
                        linea1=fgetl(fid);
                        date_ini=strtrim(linea1(findstr(linea1,':')+1:end));
                        
                       
                        try
                            t1 = datetime(date_ini,'InputFormat','dd/MM/uuuu');
                        catch
                            error_ssb=1;
                            error_txt='Error: Wrong date format';    
                            disp(error_txt);
                  
                        end
                                                
                        fclose(fid);
                        
                        try
                            serie4=readmatrix(archivoimportar);
                        catch
                            error_ssb=1;
                            error_txt='Error: File reading error';    
                            disp(error_txt);
                            disp(archivoimportar);
                  
                        end
                        
                        if error_ssb==0
                            
                            % Remove the header
                            serie4=serie4(4:end,:);
                            % Remove any NaN colum
                            serie4 = serie4(:,all(~isnan(serie4)));
                            
                            lon_series=length(serie4);
                            
                            if lon_series<app.min_days
                                error_ssb=1;
                                error_txt=['Error: The duration of the series must be greater than ',num2str(app.min_days),' days'];              
                      
                            else
                            
                                if size(serie4,2)<3 || size(serie4,2)>4
                                    
                                    error_ssb=1;
                                    error_txt='Error: The number of variables has to be 3 or 4';              
                      
                                else
                                
                                    t2=t1+days(lon_series-1);
                                    vector_tiempo=t1:t2;
                                    
                                    serie2=zeros(lon_series,13);
                                    
                                    serie2(:,1)=vector_tiempo(:).Day;
                                    serie2(:,2)=vector_tiempo(:).Month;
                                    serie2(:,3)=vector_tiempo(:).Year-vector_tiempo(1).Year+1;
                                    serie2(:,4)=serie4(:,1); % Precipitation
                                    serie2(:,8)=serie4(:,2); % T max
                                    serie2(:,9)=serie4(:,3); % T min
                                    
                                    app.default_years=serie2(end,3)-serie2(1,3)+1;
                                    
                                    app.Length_Series_Edit.Value=num2str(round(app.default_years/10));
                                    
                                    if size(serie4,2)==4
                                        serie2(:,14)=serie4(:,4); % ET0
                                        serie2(:,15)=1.15*serie4(:,4);
                                    end
                                    
                                    % Remove leap days
                                    leap_days=find(serie2(find(serie2(:,2)==2),1)==29);
                                    serie2(leap_days,:)=[];
                                    
                                    app.serieclima=serie2;
                                
                                end
                            end
                        end
                        
                    else
                      
                        error_ssb=1;
                        error_txt='Error: File could not be opened';              
                  
                    end

                  end
                
                else
                    
                    % save database if it does not exist
                    app.save_database=1; 
                    
                    % Test if exists folder 
                    line2=[app.save_folder filesep 'serieclima.mat'];
                    
                    no_serie_clima=0;
                    
                    try
                        load(line2,'serieclima');
                    catch
                        no_serie_clima=1;
              
                    end
                    
                    
                    if no_serie_clima==1
            
                        if isfolder(app.save_folder)==0
                    
                            mkdir(app.save_folder); 
                        end
                    
                        serie4=1;
                        
                        archivoimportar=[input_folder filesep 'Climate_Database_Koppen' filesep app.codiclima(2:end-1) '_salida1000'];
                       
                        load(archivoimportar);
                   
                        % Random selection of years
                        anos=app.default_years;       % Number of years to be analyzed by Hydrus
                        rema=1:1000;
                        anosselec=zeros(anos,1);
                        for iano=1:anos
                            temp=ceil(rand*length(rema));
                            anosselec(iano)=rema(temp);
                            rema(temp)=[];
                        end
                        
                        for iano=1:anos
                            filanos=find(serie(:,3)==anosselec(iano));
                            % Remove the leap years
                            temp=serie(filanos,:);
                            if length(filanos)==366 % Remove February 29 from leap years
                                temp(60,:)=[];
                            end
                            temp(:,3)=iano;
                            if iano==1
                                serie2=temp;
                            else
                                serie2=[serie2; temp];   % serie2 collects the random years picked from serie (1000 years)
                            end
                        end
                    else
                     
                        app.serieclima=serieclima;
                        base_datos=1;
                        
                    end
                end
                
                    % Calculus of the potential evaporation
                    
                    if base_datos==0 && error_ssb==0
                        
                        % Import the radiation file 'Radextra.txt'
                        archivo=[input_folder filesep 'Climate_Database_Koppen' filesep 'Radextra.txt'];
                        a=importdata(archivo);
                        if hemis==1
                            data=a.data(:,1:13);
                        else
                            data=a.data(:,[1 14:25]);
                        end
                        
                        lat=abs(lat);
                        % Interpolation for intermediate samples of lalitude
                        
                        fila=find(data(:,1)==lat);
                        if ~isempty(fila)
                            rad=data(fila,:);
                        else
                            rad(1,1)=lat;
                            fila1=find(data(:,1)<lat,1,'first');
                            fila2=find(data(:,1)>lat,1,'last');
                            for icol=2:13
                                rad(1,icol)=data(fila1,icol)+(data(fila2,icol)-data(fila1,icol))/...
                                    (data(fila2,1)-data(fila1,1))*(lat-data(fila1,1));
                            end
                        end
                        
                        if size(serie4,2)~=4
                                                 
                            % Calculus of ET0 by Hargreaves en mm/day
                            [fs,cs]=size(serie2);
                            for ifs=1:fs
                                serie2(ifs,cs+1)=0.0023*(0.5*(serie2(ifs,8)+serie2(ifs,9))+17.78)*...
                                    rad(1,1+serie2(ifs,2))*sqrt(serie2(ifs,8)-serie2(ifs,9));
                            end
                            serie2(:,cs+2)=1.15*serie2(:,cs+1);
                             
                        end
                        
                        app.serieclima=serie2;
                        clear serie2;
                        
                    end
               
                end
            
                if error_ssb==0
                    app.ClimateerrorLamp.Visible='off';
                    app.ClimateseriesobtainedsuccesfullyLamp.Visible='on';
                    app.Label_2.Visible='on';
                    app.Label_2.Text=error_txt;
                    
                    if base_datos==0 && app.save_database==1
                        
                        serieclima=app.serieclima;
                        save(line2,'serieclima');
                        clear serieclima;
                        
                    end
                    
                else 
                    app.ClimateerrorLamp.Visible='on';
                    app.ClimateseriesobtainedsuccesfullyLamp.Visible='off';
                    app.Label_2.Visible='on';
                    app.Label_2.Text=error_txt;
                end
                
                if error_ssb==0
                
                    if app.ClimateseriesobtainedsuccesfullyLamp.Visible=='on' && app.OutputvaluesobtainedsuccesfullyLamp.Visible=='on'
                    
                       
                       error=test_radon_options(app);
                       
                       if error==0
                            app.AnalyzedataButton.Enable='on';
                       else
                           app.AnalyzedataButton.Enable='off';
                       end
                       
                    end  
                end
          
        end

        % Selection changed function: SoilcharacteristicsButtonGroup
        function SoilcharacteristicsButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.SoilcharacteristicsButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Textural class')==1
                
                app.TexturalclassButtonGroup.Visible='on';
                app.PercentagesofSandSiltClaySSCPanel.Visible='off';
                                            
            end
            
            if strcmp(selectedButton.Text,'Percentages of Sand-Silt-Clay, SSC')==1
                
                app.TexturalclassButtonGroup.Visible='off';
                app.PercentagesofSandSiltClaySSCPanel.Visible='on';
                                
            end
            
            
        end

        % Button pushed function: 
        % ObtainsoilhydraulicparametersButton
        function ObtainsoilhydraulicparametersButtonPushed(app, event)
            
            input_folder=app.input_folder_default;
            
            selectedButton = app.SoilcharacteristicsButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Textural class')==1
                
                list={'Clay', 'Clay Loam','Loam','Loamy Sand','Sand','Sandy Clay',...
                    'Sandy Clay Loam','Sandy Loam','Silt','Silty Clay',...
                    'Silty Clay Loam','Silty Loam'};
                
                selectedButton2 = app.TexturalclassButtonGroup.SelectedObject;
                                               
                clase=find(strcmp(list,selectedButton2.Text));
                
                % Obtain hydraulic parameters
                delimiterIn=',';
                headerlinesIn=1;
                archivo1=importdata([input_folder filesep 'SHP_DATABASE' filesep 'Mod1_texturalclasses.txt'],delimiterIn,headerlinesIn);
                shp=archivo1.data(clase,:);
                
                % Convert pKg to Ks
                shp(5)=(10^(-shp(5)))/0.0000000000000118;
                
                % Find the percentage of clay, silt and sand
                delimiterIn=',';
                headerlinesIn=1;
                archivo1=importdata([input_folder filesep 'SHP_DATABASE' filesep 'Percentage_texturalclasses.txt'],delimiterIn,headerlinesIn);
                app.soil_percentage=archivo1.data(clase,:);
                
                app.OutputvaluesEditField.Editable='off';
                app.OutputvaluesEditField_2.Editable='off';
                app.OutputvaluesEditField_3.Editable='off';
                app.OutputvaluesEditField_4.Editable='off';
                app.OutputvaluesEditField_5.Editable='off';
                
                app.OutputvaluesEditField.Value=num2str(shp(1));
                app.OutputvaluesEditField_2.Value=num2str(shp(2));
                app.OutputvaluesEditField_3.Value=num2str(shp(3));
                app.OutputvaluesEditField_4.Value=num2str(shp(4));
                app.OutputvaluesEditField_5.Value=num2str(-log10(shp(5)*0.0000000000000118));
                
                error_ssb=0;
                error_txt='Output values obtained succesfully';
                
            end
            
            if strcmp(selectedButton.Text,'Percentages of Sand-Silt-Clay, SSC')==1
                
                ssc(1)=str2double(app.SandpercentageEditField.Value);
                ssc(2)=str2double(app.SiltpercentageEditField.Value);
                ssc(3)=str2double(app.ClaypercentageEditField.Value);
                
                check_ssc=sum(ssc);
                
                if check_ssc~=100
                    error_ssb=1;
                    error_txt='The sum of all the percentages has to sum 100%';
                else        
                
                    error_ssb=0;
                    error_txt='Output values obtained succesfully';
                 
                    app.soil_percentage=ssc;
                        
                    delimiterIn=',';
                    headerlinesIn=1;
                    archivo2=importdata([input_folder filesep 'SHP_DATABASE' filesep 'Mod2_v2.txt'],delimiterIn,headerlinesIn);
                    filas=find(archivo2.data(:,1)==ssc(1));
                    temp=archivo2.data(filas,:);
                    filas2=find(temp(:,2)==ssc(2));
                    shp=temp(filas2,5:9);
                    
                     % Convert pKg to Ks
                    shp(5)=(10^(-shp(5)))/0.0000000000000118;
                    
                  
                    app.OutputvaluesEditField.Editable='off';
                    app.OutputvaluesEditField_2.Editable='off';
                    app.OutputvaluesEditField_3.Editable='off';
                    app.OutputvaluesEditField_4.Editable='off';
                    app.OutputvaluesEditField_5.Editable='off';
                    
                    app.OutputvaluesEditField.Value=num2str(shp(1));
                    app.OutputvaluesEditField_2.Value=num2str(shp(2));
                    app.OutputvaluesEditField_3.Value=num2str(shp(3));
                    app.OutputvaluesEditField_4.Value=num2str(shp(4));
                    app.OutputvaluesEditField_5.Value=num2str(-log10(shp(5)*0.0000000000000118));
                
                end 
                
            end
              
            if error_ssb==0
                app.ErrorLamp.Visible='off';
                app.OutputvaluesobtainedsuccesfullyLamp.Visible='on';
                app.Label.Visible='on';
                app.Label.Text=error_txt;
                
                app.shp=shp;
                
            else 
                app.ErrorLamp.Visible='on';
                app.OutputvaluesobtainedsuccesfullyLamp.Visible='off';
                app.Label.Visible='on';
                app.Label.Text=error_txt;
            end
            
            if app.ClimateseriesobtainedsuccesfullyLamp.Visible=='on' && app.OutputvaluesobtainedsuccesfullyLamp.Visible=='on'
            
               error=test_radon_options(app);
               
               if error==0
                   app.AnalyzedataButton.Enable='on';
               else
                   app.AnalyzedataButton.Enable='off';
               end  
                
                               
            end  
            
            
        end

        % Button pushed function: AnalyzedataButton
        function AnalyzedataButtonPushed(app, event)
            
            % Verify the radon selection
            error=test_radon_options(app);
            
            if error==0
                
                % Check soil properties
                                
                shp_ver(1)=str2num(app.OutputvaluesEditField.Value);
                shp_ver(2)=str2num(app.OutputvaluesEditField_2.Value);
                shp_ver(3)=str2num(app.OutputvaluesEditField_3.Value);
                shp_ver(4)=str2num(app.OutputvaluesEditField_4.Value);
                shp_ver(5)=str2num(app.OutputvaluesEditField_5.Value);
                
                if shp_ver~=app.shp
                    
                    app.save_database=0;
                    app.shp=shp_ver;
                end
                
                % Execute Hydrus
                Hydrus_execution(app);
                
                if app.error_hydrus==0
                    
                    % Postprocessing of the results
                    postproccessing(app);
                    
                    % Obtain the GRP factor
                    radon_concentration(app);
                    
                    % Plot the results
                    app.Imagen_inicio2.Visible='off';
                    
                    plot_results(app);
                    pause(1);
                    
                    app.DownloadresultsButton.Enable='on';
                
                else 
                    
                    app.ErrorLamp.Visible='on';
                    app.OutputvaluesobtainedsuccesfullyLamp.Visible='off';
                    app.Label.Visible='on';
                    app.Label.Text='Convergence error in Hydrus';
                    
                    
                end    
            end
           
        end

        % Value changed function: EditvaluesCheckBox
        function EditvaluesCheckBoxValueChanged(app, event)
            value = app.EditvaluesCheckBox.Value;
            
            if value==1
                app.OutputvaluesEditField.Editable='on';
                app.OutputvaluesEditField_2.Editable='on';
                app.OutputvaluesEditField_3.Editable='on';
                app.OutputvaluesEditField_4.Editable='on';
                app.OutputvaluesEditField_5.Editable='on';
            else
                app.OutputvaluesEditField.Editable='off';
                app.OutputvaluesEditField_2.Editable='off';
                app.OutputvaluesEditField_3.Editable='off';
                app.OutputvaluesEditField_4.Editable='off';
                app.OutputvaluesEditField_5.Editable='off';
            end
            
            
        end

        % Selection changed function: RadonconcentrationButtonGroup
        function RadonconcentrationButtonGroupSelectionChanged(app, event)
            
            % Radon / Radio selection
            Rn_act=double(app.Rn_button.Value);
            
            if Rn_act==1 
                app.Rn_concentration.Editable='on';
                app.Ra_content.Editable='off';
                                            
            else  
                app.Rn_concentration.Editable='off';
                app.Ra_content.Editable='on';
                                
            end
            
        end

        % Button pushed function: TemplateButton
        function TemplateButtonPushed(app, event)
            
            name_template=[app.input_folder_default filesep 'Example_Climate_Series.txt'];
            
            [file,path] = uiputfile('Example_Climate_Series.txt');
            resultFilePath = fullfile(path,file);
             
            copyfile(name_template,resultFilePath,'f');
            
        end

        % Callback function
        function DownloadresultsButtonPushed(app, event)
            % Empty function             
            
        end

        % Value changed function: Length_Series_Edit
        function Length_Series_EditValueChanged(app, event)
            
            value = round(str2num(app.Length_Series_Edit.Value));
            
            if value<1 || value>10
                
                error_txt='Decades value has to be between 1 and 10';
                app.ClimateerrorLamp.Visible='on';
                app.ClimateseriesobtainedsuccesfullyLamp.Visible='off';
                app.Label_2.Visible='on';
                app.Label_2.Text=error_txt;
                
                app.Length_Series_Edit.Value=num2str(app.default_years/10);
                
            else
                app.default_years=10*value;
                app.default_decade=value;
                
                app.Length_Series_Edit.Value=num2str(app.default_years/10);
                
                app.ClimateerrorLamp.Visible='off';
                app.ClimateseriesobtainedsuccesfullyLamp.Visible='off';
                app.Label_2.Visible='off';
            
                app.Alert_process_time.Visible='on';
                pause(5);
                app.Alert_process_time.Visible='off';
            
            end
            
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            
            % Restart variables
            
            % Save data_base (0-> no; 1 -> yes)
            app.save_database=1;
            
            % Climate series
            app.serieclima='';
            app.codiclima='';
          
            % Years of analysis by default
            app.default_years=10;
            app.default_decade=1;
            
            % Soil characteristics
            app.shp='';
            app.soil_percentage='';
            app.default_density=1.65;
            
            % Humedad
            app.humedadmes='';
            app.humedaddiario='';
            app.humedaddiario2='';
            
            % Temperaturas;
            app.resumclimames='';
                    
            % Permeability
            app.pKg='';
            app.pKg_diario='';
            
            % Radon coefficients
            app.radon_coeficients='';
            app.GRP='';
            app.GRP_diario='';
           
           
            % Set plots to not visible
            
            cla(app.UIAxes_izq_1);
            cla(app.UIAxes_der_1);
            cla(app.UIAxes_izq_2);
            cla(app.UIAxes_der_2);
            cla(app.UIAxes_izq_3);
            cla(app.UIAxes_der_3);
            cla(app.UIAxes_izq_4);
            cla(app.UIAxes_der_4);
            
            legend(app.UIAxes_izq_1,'off');
            legend(app.UIAxes_der_1,'off');
            legend(app.UIAxes_izq_2,'off');
            legend(app.UIAxes_izq_3,'off');
            
            delete(app.bars_der_1);
            
            app.UIAxes_izq_1.Visible='off';
            app.UIAxes_der_1.Visible='off';
            app.UIAxes_izq_2.Visible='off';
            app.UIAxes_der_2.Visible='off';
            app.UIAxes_izq_3.Visible='off';
            app.UIAxes_der_3.Visible='off';
            app.UIAxes_izq_4.Visible='off';
            app.UIAxes_der_4.Visible='off';
            
            app.High_risk_lamp.Visible='off';
            app.Medium_risk_lamp.Visible='off';
            app.Low_risk_lamp.Visible='off';
            app.High_risk_label.Visible='off';
            app.Medium_risk_label.Visible='off';
            app.Low_risk_label.Visible='off';

            % Soil properties
            
            app.TexturalclassButtonGroup.Visible='on';
            app.PercentagesofSandSiltClaySSCPanel.Visible='off';
            
            app.TexturalclassButton.Value
            
            app.TexturalclassButton.Value=true;
            app.ClayButton.Value=true;
            %app.SoilcharacteristicsButtonGroup.SelectedObject='Textural class';
            %app.TexturalclassButtonGroup.SelectedObject='Clay';
            app.Bulkdensitygcm3EditField.Value=num2str(app.default_density);
            
            app.OutputvaluesEditField.Editable='off';
            app.OutputvaluesEditField_2.Editable='off';
            app.OutputvaluesEditField_3.Editable='off';
            app.OutputvaluesEditField_4.Editable='off';
            app.OutputvaluesEditField_5.Editable='off';
            
            app.OutputvaluesEditField.Value='';
            app.OutputvaluesEditField_2.Value='';
            app.OutputvaluesEditField_3.Value='';
            app.OutputvaluesEditField_4.Value='';
            app.OutputvaluesEditField_5.Value='';
            
            % Climate properties
            
            app.AfTropicalrainforestButton.Value=true;
            app.Length_Series_Edit.Value=num2str(app.default_decade);
            app.LatitudeEditField.Value=num2str(0);
            %app.LongitudeEditField.Value=num2str(0);
            
            % Radon selection
            app.Rn_button.Value=true;
            app.Rn_concentration.Value=app.default_Rn;
            
            app.Ra_button.Value=false;
            app.Ra_content.Value=app.default_Ra;
            
            % Messages
            app.ClimateerrorLamp.Visible='off';
            app.ClimateseriesobtainedsuccesfullyLamp.Visible='off';
            app.Label_2.Visible='off';
            
            app.ErrorLamp.Visible='off';
            app.OutputvaluesobtainedsuccesfullyLamp.Visible='off';
            app.Label.Visible='off';
            
            % Analyze button
            app.AnalyzedataButton.Enable='off';
            
             % Download button
            app.DownloadresultsButton.Enable='off';
            
        end

        % Button pushed function: DownloadresultsButton
        function DownloadresultsButtonPushed2(app, event)
           
            save_results(app);
            
                        
        end

        % Image clicked function: Imagen_inicio2
        function Imagen_inicio2Clicked(app, event)
            app.Imagen_inicio2.Visible='off';
            app.Imagen_inicio2.Enable='off';
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {2097, 2097};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {27, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Tag = 'Type_soil';
            app.RightPanel = uipanel(app.GridLayout);
            app.ClimateseriesKoppenclassificationorUserseriesButtonGroup = uibuttongroup(app.RightPanel);
            app.ClimateseriesKoppenclassificationorUserseriesButtonGroup.Title = 'Climate series (Koppen classification or User series)';
            app.AfTropicalrainforestButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.AfTropicalrainforestButton.Text = '[Af] Tropical, rainforest';
            app.AfTropicalrainforestButton.Value = true;
            app.AmTropicalmonsoonButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.AmTropicalmonsoonButton.Text = '[Am] Tropical, monsoon';
            app.AwTropicalsavannahButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.AwTropicalsavannahButton.Text = '[Aw] Tropical, savannah';
            app.BwhAriddeserthotButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.BwhAriddeserthotButton.Text = '[Bwh] Arid,desert,hot';
            app.BwkAriddesertcoldButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.BwkAriddesertcoldButton.Text = '[Bwk] Arid,desert,cold';
            app.BshAridsteppehotButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.BshAridsteppehotButton.Text = '[Bsh] Arid, steppe, hot';
            app.BskAridsteppecoldButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.BskAridsteppecoldButton.Text = '[Bsk] Arid, steppe, cold';
            app.CsaTemperatedrysummerhotsummerButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.CsaTemperatedrysummerhotsummerButton.Text = '[Csa] Temperate, dry summer, hot summer';
            app.CsbTemperatedrysummerwarmsummerButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.CsbTemperatedrysummerwarmsummerButton.Text = '[Csb] Temperate, dry summer, warm summer';
            app.CfaTemperatenodryseasonhotsummerButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.CfaTemperatenodryseasonhotsummerButton.Text = '[Cfa] Temperate, no dry season, hot summer';
            app.CfbTemperatenodryseasonwarmsummerButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.CfbTemperatenodryseasonwarmsummerButton.Text = '[Cfb] Temperate, no dry season, warm summer';
            app.DsaColddrysummerhotsummerButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.DsaColddrysummerhotsummerButton.Text = '[Dsa] Cold, dry summer, hot summer';
            app.DsbColddrysummerwarmsummerButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.DsbColddrysummerwarmsummerButton.Text = '[Dsb] Cold, dry summer, warm summer';
            app.DwaColddrywinterhotsummerButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.DwaColddrywinterhotsummerButton.Text = '[Dwa] Cold, dry winter, hot summer';
            app.DwbColddrywinterwarmsummerButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.DwbColddrywinterwarmsummerButton.Text = '[Dwb] Cold, dry winter, warm summer';
            app.DwcColddrywintercoldsummerButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.DwcColddrywintercoldsummerButton.Text = '[Dwc] Cold, dry winter, cold summer';
            app.DfaColdnodryseasonhotsummerButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.DfaColdnodryseasonhotsummerButton.Text = '[Dfa] Cold, no dry season, hot summer';
            app.DfbColdnodryseasonwarmsummerButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.DfbColdnodryseasonwarmsummerButton.Text = '[Dfb] Cold, no dry season, warm summer';
            app.DfcColdnodryseasoncoldsummerButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.DfcColdnodryseasoncoldsummerButton.Text = '[Dfc] Cold, no dry season, cold summer';
            app.EtPolartundraButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.EtPolartundraButton.Text = '[Et] Polar, tundra';
            app.UsUserseriesButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.UsUserseriesButton.Text = '[Us] User series';
            app.LatitudeEditFieldLabel = uilabel(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup, 'text');
            app.LatitudeEditField.Value = '0';
            app.TemplateButton = uibutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup, 'push');
            app.TemplateButton.ButtonPushedFcn = createCallbackFcn(app, @TemplateButtonPushed, true);
            app.TemplateButton.Icon = 'icono_excel.jpg';
            app.TemplateButton.Text = 'Template';
            app.Lengthofthedataseriesdecades110EditFieldLabel = uilabel(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.Lengthofthedataseriesdecades110EditFieldLabel.Text = 'Length of the data series (decades 1-10)';
            app.Length_Series_Edit = uieditfield(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup, 'text');
            app.Length_Series_Edit.ValueChangedFcn = createCallbackFcn(app, @Length_Series_EditValueChanged, true);
            app.Length_Series_Edit.Value = '1';
            app.DscColddrysummercoldsummerButton = uiradiobutton(app.ClimateseriesKoppenclassificationorUserseriesButtonGroup);
            app.DscColddrysummercoldsummerButton.Text = '[Dsc] Cold, dry summer, cold summer';
            app.PercentagesofSandSiltClaySSCPanel = uipanel(app.RightPanel);
            app.PercentagesofSandSiltClaySSCPanel.Title = 'Percentages of Sand-Silt-Clay, SSC';
            app.PercentagesofSandSiltClaySSCPanel.Visible = 'off';
            app.SandpercentageEditField_3Label = uilabel(app.PercentagesofSandSiltClaySSCPanel);
            app.SandpercentageEditField_3Label.Text = 'Sand percentage';
            app.SandpercentageEditField = uieditfield(app.PercentagesofSandSiltClaySSCPanel, 'text');
            app.SiltpercentageEditField_3Label = uilabel(app.PercentagesofSandSiltClaySSCPanel);
            app.SiltpercentageEditField_3Label.Text = 'Silt percentage ';
            app.SiltpercentageEditField = uieditfield(app.PercentagesofSandSiltClaySSCPanel, 'text');
            app.ClaypercentageEditField_3Label = uilabel(app.PercentagesofSandSiltClaySSCPanel);
            app.ClaypercentageEditField_3Label.Text = 'Clay percentage ';
            app.ClaypercentageEditField = uieditfield(app.PercentagesofSandSiltClaySSCPanel, 'text');
            app.SoilcharacteristicsButtonGroup = uibuttongroup(app.RightPanel);
            app.SoilcharacteristicsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SoilcharacteristicsButtonGroupSelectionChanged, true);
            app.SoilcharacteristicsButtonGroup.Title = 'Soil characteristics';
            app.TexturalclassButton = uiradiobutton(app.SoilcharacteristicsButtonGroup);
            app.TexturalclassButton.Text = 'Textural class';
            app.TexturalclassButton.Value = true;
            app.PercentagesofSandSiltClaySSCButton = uiradiobutton(app.SoilcharacteristicsButtonGroup);
            app.PercentagesofSandSiltClaySSCButton.Text = 'Percentages of Sand-Silt-Clay, SSC';
            app.Bulkdensitygcm3EditFieldLabel = uilabel(app.SoilcharacteristicsButtonGroup);
            app.Bulkdensitygcm3EditFieldLabel.Text = 'Bulk density (g/cm3 )';
            app.Bulkdensitygcm3EditField = uieditfield(app.SoilcharacteristicsButtonGroup, 'text');
            app.Bulkdensitygcm3EditField.Value = '1.65';
            app.TexturalclassButtonGroup = uibuttongroup(app.RightPanel);
            app.TexturalclassButtonGroup.Title = 'Textural class';
            app.ClayButton = uiradiobutton(app.TexturalclassButtonGroup);
            app.ClayButton.Text = 'Clay';
            app.ClayButton.Value = true;
            app.ClayLoamButton = uiradiobutton(app.TexturalclassButtonGroup);
            app.ClayLoamButton.Text = 'Clay Loam';
            app.LoamButton = uiradiobutton(app.TexturalclassButtonGroup);
            app.LoamButton.Text = 'Loam';
            app.LoamySandButton = uiradiobutton(app.TexturalclassButtonGroup);
            app.LoamySandButton.Text = 'Loamy Sand';
            app.SandButton = uiradiobutton(app.TexturalclassButtonGroup);
            app.SandButton.Text = 'Sand';
            app.SandyClayButton = uiradiobutton(app.TexturalclassButtonGroup);
            app.SandyClayButton.Text = 'Sandy Clay';
            app.SandyClayLoamButton = uiradiobutton(app.TexturalclassButtonGroup);
            app.SandyClayLoamButton.Text = 'Sandy Clay Loam';
            app.SandyLoamButton = uiradiobutton(app.TexturalclassButtonGroup);
            app.SandyLoamButton.Text = 'Sandy Loam';
            app.SiltButton = uiradiobutton(app.TexturalclassButtonGroup);
            app.SiltButton.Text = 'Silt';
            app.SiltyClayButton = uiradiobutton(app.TexturalclassButtonGroup);
            app.SiltyClayButton.Text = 'Silty Clay';
            app.SiltyClayLoamButton = uiradiobutton(app.TexturalclassButtonGroup);
            app.SiltyClayLoamButton.Text = 'Silty Clay Loam';
            app.SiltyLoamButton = uiradiobutton(app.TexturalclassButtonGroup);
            app.SiltyLoamButton.Text = 'Silty Loam';
            app.ObtainsoilhydraulicparametersButton = uibutton(app.RightPanel, 'push');
            app.ObtainsoilhydraulicparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ObtainsoilhydraulicparametersButtonPushed, true);
            app.ObtainsoilhydraulicparametersButton.Text = 'Obtain soil hydraulic parameters';
            app.OutputvaluesEditField = uieditfield(app.RightPanel, 'text');
            app.OutputvaluesEditField.Editable = 'off';
            app.OutputvaluesEditField_2 = uieditfield(app.RightPanel, 'text');
            app.OutputvaluesEditField_2.Editable = 'off';
            app.OutputvaluesEditField_3 = uieditfield(app.RightPanel, 'text');
            app.OutputvaluesEditField_3.Editable = 'off';
            app.OutputvaluesEditField_4 = uieditfield(app.RightPanel, 'text');
            app.OutputvaluesEditField_4.Editable = 'off';
            app.OutputvaluesEditField_5 = uieditfield(app.RightPanel, 'text');
            app.OutputvaluesEditField_5.Editable = 'off';
            app.ObtainclimateseriesButton = uibutton(app.RightPanel, 'push');
            app.ObtainclimateseriesButton.ButtonPushedFcn = createCallbackFcn(app, @Climate_Series_Button, true);
            app.ObtainclimateseriesButton.Tag = 'Obtain_Climate_Series_Button';
            app.ObtainclimateseriesButton.Text = 'Obtain climate series';
            app.OutputvaluesobtainedsuccesfullyLamp = uilamp(app.RightPanel);
            app.OutputvaluesobtainedsuccesfullyLamp.Visible = 'off';
            app.ErrorLamp = uilamp(app.RightPanel);
            app.ErrorLamp.Visible = 'off';
            app.ClimateseriesobtainedsuccesfullyLamp = uilamp(app.RightPanel);
            app.ClimateseriesobtainedsuccesfullyLamp.Visible = 'off';
            app.ClimateerrorLamp = uilamp(app.RightPanel);
            app.ClimateerrorLamp.Visible = 'off';
            app.AnalyzedataButton = uibutton(app.RightPanel, 'push');
            app.AnalyzedataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzedataButtonPushed, true);
            app.AnalyzedataButton.Enable = 'off';
            app.AnalyzedataButton.Text = 'Analyze data';
            app.Label = uilabel(app.RightPanel);
            app.Label.Visible = 'off';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Visible = 'off';
            app.RadonPotentialLabel = uilabel(app.RightPanel);
            app.RadonPotentialLabel.Text = '                                                         RadonPotential';
            app.EditvaluesCheckBox = uicheckbox(app.RightPanel);
            app.EditvaluesCheckBox.ValueChangedFcn = createCallbackFcn(app, @EditvaluesCheckBoxValueChanged, true);
            app.EditvaluesCheckBox.Text = 'Edit values';
            app.RadonconcentrationButtonGroup = uibuttongroup(app.RightPanel);
            app.RadonconcentrationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RadonconcentrationButtonGroupSelectionChanged, true);
            app.RadonconcentrationButtonGroup.Title = 'Radon concentration';
            app.Rn_button = uiradiobutton(app.RadonconcentrationButtonGroup);
            app.Rn_button.Text = '';
            app.Rn_button.Value = true;
            app.Ra_button = uiradiobutton(app.RadonconcentrationButtonGroup);
            app.Ra_button.Text = '';
            app.RnconcentrationkBqmLabel = uilabel(app.RadonconcentrationButtonGroup);
            app.RnconcentrationkBqmLabel.Text = 'Rn concentration (kBq/m  )';
            app.Rn_concentration = uieditfield(app.RadonconcentrationButtonGroup, 'numeric');
            app.Rn_concentration.Tag = 'contretation';
            app.Rn_concentration.Value = 10;
            app.RacontentBqKgEditFieldLabel = uilabel(app.RadonconcentrationButtonGroup);
            app.RacontentBqKgEditFieldLabel.Text = 'Ra content (Bq/Kg)';
            app.Ra_content = uieditfield(app.RadonconcentrationButtonGroup, 'numeric');
            app.Ra_content.Tag = 'contretation';
            app.Ra_content.Editable = 'off';
            app.Ra_content.Value = 10;
            app.RnconcentrationBqm3EditFieldLabel_2 = uilabel(app.RadonconcentrationButtonGroup);
            app.RnconcentrationBqm3EditFieldLabel_2.Text = '3';
            app.cmcmcmcmcmnpKgLabel = uilabel(app.RightPanel);
            app.cmcmcmcmcmnpKgLabel.Text = '(cm ·cm   )      (cm  ·cm   )          (cm   )               n(-)            pKg(-)';
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '       3      -3               3      -3                   -1    ';
            app.rsLabel = uilabel(app.RightPanel);
            app.rsLabel.Text = 'r                         s                                                                                ';
            app.Label_4 = uilabel(app.RightPanel);
            app.Label_4.Text = '0';
            app.Label_5 = uilabel(app.RightPanel);
            app.Label_5.Text = '-';
            app.Label_6 = uilabel(app.RightPanel);
            app.Label_6.Text = '-';
            app.Label_7 = uilabel(app.RightPanel);
            app.Label_7.Text = '0';
            app.oLabel = uilabel(app.RightPanel);
            app.oLabel.Text = 'o';
            app.Label_8 = uilabel(app.RightPanel);
            app.Label_8.Text = '\';
            app.Label_9 = uilabel(app.RightPanel);
            app.Label_9.Text = '/';
            app.Alert_process_time = uilabel(app.RightPanel);
            app.Alert_process_time.Visible = 'off';
            app.Alert_process_time.Text = 'The proccess takes approximatedly 1 minute per decade';
            app.RestartButton = uibutton(app.RightPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.UNIVERSIDADDEALICANTELabel = uilabel(app.RightPanel);
            app.UNIVERSIDADDEALICANTELabel.Text = '2023   UNIVERSIDAD DE ALICANTE';
            app.Imagen_logo = uiimage(app.RightPanel);
            app.Imagen_logo.ImageSource = 'logo.svg';
            app.EstimatedprocessingtimesGaugeLabel = uilabel(app.RightPanel);
            app.EstimatedprocessingtimesGaugeLabel.Text = 'Estimated processing time (s)';
            app.EstimatedprocessingtimesGauge = uigauge(app.RightPanel, 'circular');
            app.EstimatedprocessingtimesGauge.Limits = [0 10];
            app.DownloadresultsButton = uibutton(app.RightPanel, 'push');
            app.DownloadresultsButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadresultsButtonPushed2, true);
            app.DownloadresultsButton.Enable = 'off';
            app.DownloadresultsButton.Text = 'Download results';
            app.Imagen_inicio2 = uiimage(app.RightPanel);
            app.Imagen_inicio2.ImageClickedFcn = createCallbackFcn(app, @Imagen_inicio2Clicked, true);
            app.Imagen_inicio2.ImageSource = 'imagen_inicial_2.png';
            app.VisitorsLabel = uilabel(app.RightPanel);
            app.VisitorsLabel.Text = 'Visitors:';
            app.VisitorsEditField = uieditfield(app.RightPanel, 'numeric');
            app.VisitorsEditField.Value = 100;
            app.Low_risk_label = uilabel(app.RightPanel);
            app.Low_risk_label.Visible = 'off';
            app.Low_risk_label.Text = 'Low risk (GRP <10)';
            app.Medium_risk_label = uilabel(app.RightPanel);
            app.Medium_risk_label.Visible = 'off';
            app.Medium_risk_label.Text = 'Medium risk (10 <= GRP < 35)';
            app.High_risk_label = uilabel(app.RightPanel);
            app.High_risk_label.Visible = 'off';
            app.High_risk_label.Text = 'High risk (GRP >= 35)';
            app.Low_risk_lamp = uilamp(app.RightPanel);
            app.Low_risk_lamp.Visible = 'off';
            app.Medium_risk_lamp = uilamp(app.RightPanel);
            app.Medium_risk_lamp.Visible = 'off';
            app.High_risk_lamp = uilamp(app.RightPanel);
            app.High_risk_lamp.Visible = 'off';
            app.UIAxes_izq_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_izq_4, 'Title')
            xlabel(app.UIAxes_izq_4, 'X')
            ylabel(app.UIAxes_izq_4, 'Y')
            zlabel(app.UIAxes_izq_4, 'Z')
            app.UIAxes_izq_4.Visible = 'off';
            app.UIAxes_izq_1 = uiaxes(app.RightPanel);
            title(app.UIAxes_izq_1, 'Title')
            xlabel(app.UIAxes_izq_1, 'X')
            ylabel(app.UIAxes_izq_1, 'Y')
            zlabel(app.UIAxes_izq_1, 'Z')
            app.UIAxes_izq_1.Visible = 'off';
            app.UIAxes_der_1 = uiaxes(app.RightPanel);
            title(app.UIAxes_der_1, 'Title')
            xlabel(app.UIAxes_der_1, 'X')
            ylabel(app.UIAxes_der_1, 'Y')
            zlabel(app.UIAxes_der_1, 'Z')
            app.UIAxes_der_1.Visible = 'off';
            app.UIAxes_izq_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_izq_2, 'Title')
            xlabel(app.UIAxes_izq_2, 'X')
            ylabel(app.UIAxes_izq_2, 'Y')
            zlabel(app.UIAxes_izq_2, 'Z')
            app.UIAxes_izq_2.Visible = 'off';
            app.UIAxes_der_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_der_2, 'Title')
            xlabel(app.UIAxes_der_2, 'X')
            ylabel(app.UIAxes_der_2, 'Y')
            zlabel(app.UIAxes_der_2, 'Z')
            app.UIAxes_der_2.Visible = 'off';
            app.UIAxes_izq_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_izq_3, 'Title')
            xlabel(app.UIAxes_izq_3, 'X')
            ylabel(app.UIAxes_izq_3, 'Y')
            zlabel(app.UIAxes_izq_3, 'Z')
            app.UIAxes_izq_3.Visible = 'off';
            app.UIAxes_der_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_der_3, 'Title')
            xlabel(app.UIAxes_der_3, 'X')
            ylabel(app.UIAxes_der_3, 'Y')
            zlabel(app.UIAxes_der_3, 'Z')
            app.UIAxes_der_3.Visible = 'off';
            app.UIAxes_der_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_der_4, 'Title')
            xlabel(app.UIAxes_der_4, 'X')
            ylabel(app.UIAxes_der_4, 'Y')
            zlabel(app.UIAxes_der_4, 'Z')
            app.UIAxes_der_4.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RadonPotential

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end